classdef covideo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CovideoUIFigure              matlab.ui.Figure
        LiveButton                   matlab.ui.control.Button
        StopButton                   matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        MaxTempratureEditFieldLabel  matlab.ui.control.Label
        MaxTempratureEditField       matlab.ui.control.NumericEditField
        AverageTempratureEditFieldLabel  matlab.ui.control.Label
        AverageTempratureEditField   matlab.ui.control.NumericEditField
        Lamp                         matlab.ui.control.Lamp
        Gauge                        matlab.ui.control.LinearGauge
    end

    
    % Programmed by Javad Rahimipour Anaraki on 20.03.24
    % Javad Rahimipour Anaraki | Postdoctoral Fellow
    % PRISM Lab, Bloorview Research Institute
    % Holland Bloorview Kids Rehabilitation Hospital
    % jrahimipour [AT] hollandbloorview [DOT] ca | http://individual.utoronto.ca/jrahimipour/
    
    properties (Access = public)
        stopFlag = false;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Image.ImageSource = ones([480, 640, 3]);
            app.StopButton.Enable = false;
        end

        % Button pushed function: LiveButton
        function LiveButtonPushed(app, event)
            
            app.LiveButton.Enable = false;
            app.StopButton.Enable = true;
            app.Lamp.Color = 'green';
            app.Gauge.Value = 10;
            
            %Loading classifier
            detector = vision.CascadeObjectDetector;
            shapeInserter = vision.ShapeInserter('BorderColor','Custom','CustomBorderColor',[0 0 0]);
            
            while ~(app.stopFlag)
                                
                %copyfile 'THRImage.csv' 'THRImage1.csv'
                THRImage = readmatrix('THRImage.csv') - 273.15;
                if (size(THRImage, 1) < 480)
                    disp('File size is small!');
                    continue;
                end
                temps = THRImage;
                THRImage = mat2gray(THRImage);
                imwrite(THRImage, 'THRImage.jpg');
                bwTHRImage = imread('THRImage.jpg');
                app.Image.ImageSource = 'THRImage.jpg';
                drawnow;
                
                %Face bounding box
                bbox = step(detector, THRImage);
                if (isempty(bbox))
                    app.Lamp.Color = 'green';
                    app.MaxTempratureEditField.Value = 0;
                    app.AverageTempratureEditField.Value = 0;
                    continue;
                end
                
                %disp('Found!');
                [~, val] = max(bbox(:, 3));
                bbox = bbox(val, :);
                
                %Detect canthus area
                points = detectMinEigenFeatures(THRImage, 'ROI', bbox, 'FilterSize', 15);
                
                xCentre = (max(points.Location(:, 1)) - min(points.Location(:, 1))) / 2;
                xCentre = xCentre + min(points.Location(:, 1));
                yCentre = (max(points.Location(:, 2)) - min(points.Location(:, 2))) / 2;
                yCentre = yCentre + min(points.Location(:, 2));
                
                canthus = floor([xCentre - 40, yCentre - 10, 80, 20]);                
                canthusTemp = temps(canthus(1):canthus(1)+canthus(3), canthus(2):canthus(2)+canthus(4));
                
                %Show the detected region
                cordinates = step(shapeInserter, ind2rgb(bwTHRImage, hot), int32(canthus));
                app.Image.ImageSource = cordinates;
                
                %Calculate max and mean of temprature
                app.MaxTempratureEditField.Value = double(max(canthusTemp(:)));
                app.AverageTempratureEditField.Value = double(mean(canthusTemp(:)));
                if (app.MaxTempratureEditField.Value >= 38)
                    app.Lamp.Color = 'red';
                    beep;pause(.5);beep;pause(.5);beep;
                    f = msgbox('High temprature detected!', 'Error','error');
                    StopButtonPushed(app, event)
                else
                    app.Lamp.Color = 'green';
                end
                drawnow;
                
                for i=10:-1:0
                    app.Gauge.Value = i;
                    pause(1);
                end
                
            end
            
            app.stopFlag = false;
        end

        % Callback function
        function RGBCameraDropDownValueChanged(app, event)
            
        end

        % Callback function
        function LiveSwitchValueChanged(app, event)
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopFlag = true;
            app.LiveButton.Enable = true;
            app.StopButton.Enable = false;
        end

        % Close request function: CovideoUIFigure
        function CovideoUIFigureCloseRequest(app, event)
            StopButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CovideoUIFigure = uifigure('Visible', 'off');
            app.CovideoUIFigure.Name = 'Covideo';
            app.CovideoUIFigure.CloseRequestFcn = createCallbackFcn(app, @CovideoUIFigureCloseRequest, true);
            app.LiveButton = uibutton(app.CovideoUIFigure, 'push');
            app.LiveButton.ButtonPushedFcn = createCallbackFcn(app, @LiveButtonPushed, true);
            app.LiveButton.Text = {'Live'; ''};
            app.StopButton = uibutton(app.CovideoUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Image = uiimage(app.CovideoUIFigure);
            app.MaxTempratureEditFieldLabel = uilabel(app.CovideoUIFigure);
            app.MaxTempratureEditFieldLabel.Text = 'Max Temprature';
            app.MaxTempratureEditField = uieditfield(app.CovideoUIFigure, 'numeric');
            app.MaxTempratureEditField.Limits = [0 Inf];
            app.MaxTempratureEditField.ValueDisplayFormat = '%.1f';
            app.MaxTempratureEditField.Editable = 'off';
            app.AverageTempratureEditFieldLabel = uilabel(app.CovideoUIFigure);
            app.AverageTempratureEditFieldLabel.Text = 'Average Temprature';
            app.AverageTempratureEditField = uieditfield(app.CovideoUIFigure, 'numeric');
            app.AverageTempratureEditField.Limits = [0 Inf];
            app.AverageTempratureEditField.ValueDisplayFormat = '%.1f';
            app.AverageTempratureEditField.Editable = 'off';
            app.Lamp = uilamp(app.CovideoUIFigure);
            app.Gauge = uigauge(app.CovideoUIFigure, 'linear');
            app.Gauge.Limits = [0 10];
            app.Gauge.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.Gauge.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.Gauge.Value = 10;
            % Show the figure after all components are created
            app.CovideoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = covideo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CovideoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CovideoUIFigure)
        end
    end
end